classdef RGBCorrelator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Slider3       matlab.ui.control.Slider
        Slider3Label  matlab.ui.control.Label
        Slider2       matlab.ui.control.Slider
        Slider2Label  matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        Button        matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        t1 % Description
        t2 % Description
        t3
        
        filePath = '/Users/azadehwork/Desktop/Literature-Ga-XRF/April.2021/For_Seim/Heart_LA_ICP_MS/H107-1';
        path_S = '/S34.txt'
        path_Gd = '/Gd158.txt'
        path_P = '/P31.txt'
        path_Ca = '/Ca44.txt'
        path_Cu = '/Cu65.txt'
        path_Fe = '/Fe57.txt'
        path_Zn = '/Zn66.txt'
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            func(app)

%  elements not scaled unit [cps]
S=load([app.filePath app.path_S]);
Gd=load([app.filePath app.path_Gd]);
P=load([app.filePath app.path_P]);
Ca=load([app.filePath app.path_Ca]);
Cu=load([app.filePath app.path_Cu]);
Fe=load([app.filePath app.path_Fe]);
Zn=load([app.filePath app.path_Zn]);

[BW_S,MaskFrom_S] = segmentImageLAICPMS_S(S);

maskedImage_S=BW_S.*S;
maskedImage_Gd= BW_S.*Gd;
maskedImage_P= BW_S.*P;
maskedImage_Ca=BW_S.*Ca;
maskedImage_Cu=BW_S.*Cu;
maskedImage_Fe=BW_S.*Fe;
maskedImage_Zn=BW_S.*Zn;

min_Gd=min(maskedImage_Gd(:));
max_Gd=max(maskedImage_Gd(:));

app.t1 = max_Gd;

min_S=min(maskedImage_S(:));
max_S=max(maskedImage_S(:));

app.t2 = max_S;

min_Zn=min(maskedImage_Zn(:));
max_Zn=max(maskedImage_Zn(:));

%app.t3 = max_Zn;

min_Cu=min(maskedImage_Cu(:));
max_Cu=max(maskedImage_Cu(:));

%app.t3 = max_Cu;

min_Fe=min(maskedImage_Fe(:));
max_Fe=max(maskedImage_Fe(:));

%app.t3 = max_Fe;

min_P=min(maskedImage_P(:));
max_P=max(maskedImage_P(:));

app.t3 = max_P;

min_Ca=min(maskedImage_Ca(:));
max_Ca=max(maskedImage_Ca(:));

%app.t3 = max_Ca;

test_Gd=(maskedImage_Gd-min_Gd)/(app.t1-min_Gd);
test_S=(maskedImage_S-min_S)/(app.t2-min_S);
%test_Zn=(maskedImage_Zn-min_Zn)/(app.t3-min_Zn);
%test_Cu=(maskedImage_Cu-min_Cu)/(app.t3-min_Cu);
%test_Fe=(maskedImage_Fe-min_Fe)/(app.t3-min_Fe);
test_P=(maskedImage_P-min_P)/(app.t3-min_P);
%test_Ca=(maskedImage_Ca-min_Ca)/(app.t3-min_Ca);

rgbImage = cat(3, test_Gd,test_S, test_P);
imagesc(rgbImage,'Parent',app.UIAxes); 
%imshow('/Users/azadehwork/Desktop/1111.png','Parent', app.UIAxes);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            S=load([app.filePath app.path_S]);
Gd=load([app.filePath app.path_Gd]);
P=load([app.filePath app.path_P]);
Ca=load([app.filePath app.path_Ca]);
Cu=load([app.filePath app.path_Cu]);
Fe=load([app.filePath app.path_Fe]);
Zn=load([app.filePath app.path_Zn]);


[BW_S,MaskFrom_S] = segmentImageLAICPMS_S(S);

maskedImage_S=BW_S.*S;
maskedImage_Gd= BW_S.*Gd;
maskedImage_P= BW_S.*P;
maskedImage_Ca=BW_S.*Ca;
maskedImage_Cu=BW_S.*Cu;
maskedImage_Fe=BW_S.*Fe;
maskedImage_Zn=BW_S.*Zn;

min_Gd=min(maskedImage_Gd(:));
max_Gd=max(maskedImage_Gd(:));

app.t1 = max_Gd - (max_Gd-min_Gd) / 100 * value;

min_S=min(maskedImage_S(:));
max_S=max(maskedImage_S(:));

min_Zn=min(maskedImage_Zn(:));
max_Zn=max(maskedImage_Zn(:));

min_Cu=min(maskedImage_Cu(:));
max_Cu=max(maskedImage_Cu(:));

min_Fe=min(maskedImage_Fe(:));
max_Fe=max(maskedImage_Fe(:));

min_P=min(maskedImage_P(:));
max_P=max(maskedImage_P(:));

min_Ca=min(maskedImage_Ca(:));
max_Ca=max(maskedImage_Ca(:));

test_Gd=(maskedImage_Gd-min_Gd)/(app.t1-min_Gd);
test_S=(maskedImage_S-min_S)/(app.t2-min_S);
%test_Zn=(maskedImage_Zn-min_Zn)/(app.t3-min_Zn);
%test_Cu=(maskedImage_Cu-min_Cu)/(app.t3-min_Cu);
%test_Fe=(maskedImage_Fe-min_Fe)/(app.t3-min_Fe);
test_P=(maskedImage_P-min_P)/(app.t3-min_P);
%test_Ca=(maskedImage_Ca-min_Ca)/(app.t3-min_Ca);

rgbImage = cat(3, test_Gd,test_S, test_P);
imagesc(rgbImage,'Parent',app.UIAxes); 
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            S=load([app.filePath app.path_S]);
Gd=load([app.filePath app.path_Gd]);
P=load([app.filePath app.path_P]);
Ca=load([app.filePath app.path_Ca]);
Cu=load([app.filePath app.path_Cu]);
Fe=load([app.filePath app.path_Fe]);
Zn=load([app.filePath app.path_Zn]);


[BW_S,MaskFrom_S] = segmentImageLAICPMS_S(S);

maskedImage_S=BW_S.*S;
maskedImage_Gd= BW_S.*Gd;
maskedImage_P= BW_S.*P;
maskedImage_Ca=BW_S.*Ca;
maskedImage_Cu=BW_S.*Cu;
maskedImage_Fe=BW_S.*Fe;
maskedImage_Zn=BW_S.*Zn;

min_Gd=min(maskedImage_Gd(:));
max_Gd=max(maskedImage_Gd(:));


min_S=min(maskedImage_S(:));
max_S=max(maskedImage_S(:));

app.t2 = max_S - (max_S-min_S) / 100 * value;

min_Zn=min(maskedImage_Zn(:));
max_Zn=max(maskedImage_Zn(:));

min_Cu=min(maskedImage_Cu(:));
max_Cu=max(maskedImage_Cu(:));

min_Fe=min(maskedImage_Fe(:));
max_Fe=max(maskedImage_Fe(:));

min_P=min(maskedImage_P(:));
max_P=max(maskedImage_P(:));

min_Ca=min(maskedImage_Ca(:));
max_Ca=max(maskedImage_Ca(:));

test_Gd=(maskedImage_Gd-min_Gd)/(app.t1-min_Gd);
test_S=(maskedImage_S-min_S)/(app.t2-min_S);
%test_Zn=(maskedImage_Zn-min_Zn)/(app.t3-min_Zn);
%test_Cu=(maskedImage_Cu-min_Cu)/(app.t3-min_Cu);
%test_Fe=(maskedImage_Fe-min_Fe)/(app.t3-min_Fe);
test_P=(maskedImage_P-min_P)/(app.t3-min_P);
%test_Ca=(maskedImage_Ca-min_Ca)/(app.t3-min_Ca);

rgbImage = cat(3, test_Gd,test_S, test_P);
imagesc(rgbImage,'Parent',app.UIAxes); 
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            S=load([app.filePath app.path_S]);
Gd=load([app.filePath app.path_Gd]);
P=load([app.filePath app.path_P]);
Ca=load([app.filePath app.path_Ca]);
Cu=load([app.filePath app.path_Cu]);
Fe=load([app.filePath app.path_Fe]);
Zn=load([app.filePath app.path_Zn]);


[BW_S,MaskFrom_S] = segmentImageLAICPMS_S(S);

maskedImage_S=BW_S.*S;
maskedImage_Gd= BW_S.*Gd;
maskedImage_P= BW_S.*P;
maskedImage_Ca=BW_S.*Ca;
maskedImage_Cu=BW_S.*Cu;
maskedImage_Fe=BW_S.*Fe;
maskedImage_Zn=BW_S.*Zn;

min_Gd=min(maskedImage_Gd(:));
max_Gd=max(maskedImage_Gd(:));


min_S=min(maskedImage_S(:));
max_S=max(maskedImage_S(:));


min_Zn=min(maskedImage_Zn(:));
max_Zn=max(maskedImage_Zn(:));

%app.t3 = max_Zn - (max_Zn-min_Zn) / 100 * value;


min_Cu=min(maskedImage_Cu(:));
max_Cu=max(maskedImage_Cu(:));

%app.t3 = max_Cu - (max_Cu-min_Cu) / 100 * value;


min_Fe=min(maskedImage_Fe(:));
max_Fe=max(maskedImage_Fe(:));

%app.t3 = max_Fe - (max_Fe-min_Fe) / 100 * value;


min_P=min(maskedImage_P(:));
max_P=max(maskedImage_P(:));

app.t3 = max_P - (max_P-min_P) / 100 * value;


min_Ca=min(maskedImage_Ca(:));
max_Ca=max(maskedImage_Ca(:));

%app.t3 = max_Ca - (max_Ca-min_Ca) / 100 * value;


test_Gd=(maskedImage_Gd-min_Gd)/(app.t1-min_Gd);
test_S=(maskedImage_S-min_S)/(app.t2-min_S);
%test_Zn=(maskedImage_Zn-min_Zn)/(app.t3-min_Zn);
%test_Cu=(maskedImage_Cu-min_Cu)/(app.t3-min_Cu);
%test_Fe=(maskedImage_Fe-min_Fe)/(app.t3-min_Fe);
test_P=(maskedImage_P-min_P)/(app.t3-min_P);
%test_Ca=(maskedImage_Ca-min_Ca)/(app.t3-min_Ca);

rgbImage = cat(3, test_Gd,test_S, test_P);
imagesc(rgbImage,'Parent',app.UIAxes); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGBCorrelator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end